classdef Audio_Editor_Recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        grabar                 matlab.ui.control.Button           % Grabar
        cargar                 matlab.ui.control.Button           % Cargar
        LabelNumericEditField  matlab.ui.control.Label            % Frecuen...
        frecuenciaMuestreo     matlab.ui.control.NumericEditField % [1000 Inf]
        Label                  matlab.ui.control.Label            % Tiempo ...
        tiempoGrabacion        matlab.ui.control.NumericEditField % [5 30]
        grafica                matlab.ui.control.UIAxes           % Señal d...
        fourier                matlab.ui.control.UIAxes           % Transfo...
        LabelNumericEditField2 matlab.ui.control.Label            % Nuevo t...
        tiempoInicial          matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                 matlab.ui.control.Label            % Nuevo t...
        tiempoFinal            matlab.ui.control.NumericEditField % [-Inf Inf]
        recortar               matlab.ui.control.Button           % Recortar
        LabelNumericEditField3 matlab.ui.control.Label            % Atenuación
        atenuacion             matlab.ui.control.NumericEditField % [-Inf Inf]
        atenuar                matlab.ui.control.Button           % Atenuar
        invertir               matlab.ui.control.Button           % Invertir
        retardar               matlab.ui.control.Button           % Retardo
        LabelNumericEditField4 matlab.ui.control.Label            % Retardo
        retardo                matlab.ui.control.NumericEditField % [0 Inf]
        LabelListBox           matlab.ui.control.Label            % List Box
        lista                  matlab.ui.control.ListBox          % Item 1,...
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % grabar button pushed function
        function grabarButtonPushed(app)
            senal_salida=audiorecorder(app.frecuenciaMuestreo.Value,16,1);%Creacion del objeto de grabacion
            recordblocking(senal_salida,app.tiempoGrabacion.Value);%Grabacion del sonido
            senal_grabada=getaudiodata(senal_salida, 'single'); %Paso los valores del objeto a una señal
            %Grabamos y guardamos la señal
            audiowrite('audio.wav',senal_grabada,app.frecuenciaMuestreo.Value);
            
            plot(app.grafica, senal_grabada)
            title(app.grafica,'Señal de Audio Grabada')

            [f,X]=spectrumGen(senal_grabada,app.frecuenciaMuestreo.Value,1); %Espectro bidireccional de la señal y vector de frecuencia
            plot(app.fourier,f,X)%Dibujar la señal
            xlabel(app.fourier,'Frecuencia,[Hz]','fontsize',16,'FontAngle','Italic');
            ylabel(app.fourier,'Amplitud','fontsize',16);
            
        end

        % recortar button pushed function
        function recortarButtonPushed(app)
            signal = audioread('audio.wav');
            
            nsignal = signal(app.frecuenciaMuestreo.Value*app.tiempoInicial.Value:app.frecuenciaMuestreo.Value*app.tiempoFinal.Value);
            
            plot(app.grafica, nsignal)
           
            [f,X]=spectrumGen(nsignal,app.frecuenciaMuestreo.Value,2); %Espectro bidireccional de la señal y vector de frecuencia
            plot(app.fourier,f,X) %Dibujar la señal
            xlabel(app.fourier,'Frecuencia,[Hz]','fontsize',16,'FontAngle','Italic');
            ylabel(app.fourier,'Amplitud','fontsize',16);
        end

        % atenuar button pushed function
        function atenuarButtonPushed(app)
            signal = audioread('audio.wav');
            
            nsignal = signal/app.atenuacion.Value;
            
            plot(app.grafica, nsignal)
            title(app.grafica,'Señal de Audio Grabada')
           
            [f,X]=spectrumGen(nsignal,app.frecuenciaMuestreo.Value,2); %Espectro bidireccional de la señal y vector de frecuencia
            plot(app.fourier,f,X) %Dibujar la señal
            xlabel(app.fourier,'Frecuencia,[Hz]','fontsize',16,'FontAngle','Italic');
            ylabel(app.fourier,'Amplitud','fontsize',16);
        end

        % invertir button pushed function
        function invertirButtonPushed(app)
            [y fs] = audioread('audio.wav');
            b = length(y);
            
            for i=1: (b)
                y1(i) = y(b-i+1);
            end

            plot(app.grafica, y1)
            title(app.grafica,'Señal Invertida')
            
            sound(y,fs)
            pause(5)
            sound(y1,fs)
        end

        % retardar button pushed function
        function retardarButtonPushed(app)
            [y fs] = audioread('audio.wav');
            b = length(y);
            
            for i=1: (b)
                y1(i) = y(i)+app.retardo.Value;
            end
            
            plot(app.grafica, y1)
            title(app.grafica,'Señal Retardada')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.grabar = uibutton(app.UIFigure, 'push');
            app.grabar.ButtonPushedFcn = createCallbackFcn(app, @grabarButtonPushed);
            app.grabar.Text = 'Grabar';
            app.cargar = uibutton(app.UIFigure, 'push');
            app.cargar.Text = 'Cargar';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Frecuencia de muestreo';
            app.frecuenciaMuestreo = uieditfield(app.UIFigure, 'numeric');
            app.frecuenciaMuestreo.Limits = [1000 Inf];
            app.frecuenciaMuestreo.Value = 44100;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Tiempo de grabación';
            app.tiempoGrabacion = uieditfield(app.UIFigure, 'numeric');
            app.tiempoGrabacion.Limits = [5 30];
            app.tiempoGrabacion.Value = 5;
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Señal de Audio Grabada');
            xlabel(app.grafica, 'X');
            ylabel(app.grafica, 'Y');
            app.fourier = uiaxes(app.UIFigure);
            title(app.fourier, 'Transformada de Fourier');
            xlabel(app.fourier, 'X');
            ylabel(app.fourier, 'Y');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Nuevo tiempo inicial';
            app.tiempoInicial = uieditfield(app.UIFigure, 'numeric');
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Nuevo tiempo final';
            app.tiempoFinal = uieditfield(app.UIFigure, 'numeric');
            app.tiempoFinal.Value = 5;
            app.recortar = uibutton(app.UIFigure, 'push');
            app.recortar.ButtonPushedFcn = createCallbackFcn(app, @recortarButtonPushed);
            app.recortar.Text = 'Recortar';
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Atenuación';
            app.atenuacion = uieditfield(app.UIFigure, 'numeric');
            app.atenuar = uibutton(app.UIFigure, 'push');
            app.atenuar.ButtonPushedFcn = createCallbackFcn(app, @atenuarButtonPushed);
            app.atenuar.Text = 'Atenuar';
            app.invertir = uibutton(app.UIFigure, 'push');
            app.invertir.ButtonPushedFcn = createCallbackFcn(app, @invertirButtonPushed);
            app.invertir.Text = 'Invertir';
            app.retardar = uibutton(app.UIFigure, 'push');
            app.retardar.ButtonPushedFcn = createCallbackFcn(app, @retardarButtonPushed);
            app.retardar.Text = 'Retardo';
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Retardo';
            app.retardo = uieditfield(app.UIFigure, 'numeric');
            app.retardo.Limits = [0 Inf];
            app.retardo.Value = 1;
            app.LabelListBox = uilabel(app.UIFigure);
            app.LabelListBox.Text = 'List Box';
            app.lista = uilistbox(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Audio_Editor_Recorder()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end